classdef visualisation_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ColouringButtonGroup      matlab.ui.container.ButtonGroup
        IterationsButton          matlab.ui.control.RadioButton
        ConvergenceButton         matlab.ui.control.RadioButton
        NoColouringButton         matlab.ui.control.RadioButton
        DrawOriginalPlotCheckBox  matlab.ui.control.CheckBox
        IterationSliderLabel      matlab.ui.control.Label
        IterationSlider           matlab.ui.control.Slider
        DegreeSpinnerLabel        matlab.ui.control.Label
        DegreeSpinner             matlab.ui.control.Spinner
        RandomiseButton           matlab.ui.control.Button
        DrawRootsCheckBox         matlab.ui.control.CheckBox
        IterationIncrementButton  matlab.ui.control.Button
        IterationDecrementButton  matlab.ui.control.Button
        NPointsLabel              matlab.ui.control.Label
        NPointsSpinner            matlab.ui.control.Spinner
        RecalcRedrawButton        matlab.ui.control.Button
        ASliderLabel              matlab.ui.control.Label
        ASlider                   matlab.ui.control.Slider
        IndexSpinnerLabel         matlab.ui.control.Label
        IndexSpinner              matlab.ui.control.Spinner
        RedrawOnChangeCheckBox    matlab.ui.control.CheckBox
        PrintAButton              matlab.ui.control.Button
        DrawMarkersCheckBox       matlab.ui.control.CheckBox
        DrawZeroLineCheckBox      matlab.ui.control.CheckBox
        AEditFieldLabel           matlab.ui.control.Label
        AEditField                matlab.ui.control.EditField
        ManualInputLabel          matlab.ui.control.Label
        ParametersLabel           matlab.ui.control.Label
        StyleLabel                matlab.ui.control.Label
        ExamplesDropDownLabel     matlab.ui.control.Label
        ExamplesDropDown          matlab.ui.control.DropDown
        PrintRootsButton          matlab.ui.control.Button
        DebugMenuLabel            matlab.ui.control.Label
        MSizeSpinnerLabel         matlab.ui.control.Label
        MSizeSpinner              matlab.ui.control.Spinner
        RSizeSpinnerLabel         matlab.ui.control.Label
        RSizeSpinner              matlab.ui.control.Spinner
        CalcRootsOnlyCheckBox     matlab.ui.control.CheckBox
        LimitToPeriodCheckBox     matlab.ui.control.CheckBox
        ClearInputButton          matlab.ui.control.Button
        SetZeroButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        A % Vector of coefficients
        B % Vector of derivative coefficents
        iterationsValues % matrix of values from a given iteration
        roots % Description
        x % Description
        y % Description
        valuesRange % Description
        
        iterationsColourMap % Description
        convergenceColourMap % Description
        iterations % Description
        tolerance % Description
        rootsColourMap % Description
        palette % Description
        plotRange % Description
    end
    
    methods (Access = private)
        
        function redrawaxes(app)
            if (app.CalcRootsOnlyCheckBox.Value == 0)
                iteration = floor(app.IterationSlider.Value) + 1;
                xCurr = app.iterationsValues(iteration,:);
                yCurr = real(goertzel(app.A, xCurr, true));
            end
            yRoots = real(goertzel(app.A, app.roots, true));
            cla(app.UIAxes);
            % Drawing -----------------------------------------------------
            % Colour maps
            if (app.IterationsButton.Value == 1)
                cmap = app.iterationsColourMap;
                cmap_roots = [0, 0, 1];
            elseif (app.ConvergenceButton.Value == 1)
                cmap = app.convergenceColourMap;
                if (app.CalcRootsOnlyCheckBox.Value == 1)
                    cmap_roots = [1, 0, 0];
                else
                    cmap_roots = app.rootsColourMap;
                end
            else
                cmap = repmat([0.4 0.4 0.8], length(xCurr), 1);
                cmap_roots = [1, 0, 0];
            end
            
            hold(app.UIAxes, "on");
            
            % Zero line
            if (app.DrawZeroLineCheckBox.Value == 1)
                plot(app.UIAxes, app.x([1, end]), [0, 0], 'b--');
            end
    
            % Original
            if (app.DrawOriginalPlotCheckBox.Value == 1)
               plot(app.UIAxes, app.x, app.y);
            end
            
            % Main
            if (app.CalcRootsOnlyCheckBox.Value == 0)
                mSize = app.MSizeSpinner.Value;
                if (app.DrawMarkersCheckBox.Value == 1)
                    scatter(app.UIAxes, xCurr, yCurr, mSize, cmap, 'filled');
                end
            end

            % Roots
            rSize = app.RSizeSpinner.Value;
            if (app.DrawRootsCheckBox.Value == 1)
                scatter(app.UIAxes, app.roots, yRoots, rSize, cmap_roots, 'filled','MarkerEdgeColor',[0 0 0]);
            end
            
            % -------------------------------------------------------------
            hold(app.UIAxes, "off");
            grid(app.UIAxes, "on");
            xlim(app.UIAxes, [0, app.plotRange])
            ylim(app.UIAxes,[app.valuesRange(1)-0.1, app.valuesRange(2)+0.1]);
            
        end
        
        function recalculate(app, randomise)
            if (nargin == 1)
                randomise = true;
            end
            if (randomise)
                degree = app.DegreeSpinner.Value;
                app.A = rand(1, degree+1) * 2 - 1;
                if (app.IndexSpinner.Value + 1 <= length(app.A))
                    app.ASlider.Value = app.A(app.IndexSpinner.Value + 1);
                end
            end
            app.AEditField.Value = sprintf('%.2f ', app.A);%num2str(app.A);
            if (app.LimitToPeriodCheckBox.Value == 0)
                app.plotRange = 2*pi;
            else
               app.plotRange = calculateperiod(app.A); 
            end
            app.B = -app.A.*(0:(length(app.A)-1));

            N = app.NPointsSpinner.Value;
            % We can first calculate values for half the period
            % and then mirror them about period/2 and repeat the matrix
            % 2*pi/period times (assuming period <= 2*pi)
            period = calculateperiod(app.A);
            app.x = linspace(0, period/2, N);
            app.y = real(goertzel(app.A, app.x, true));
            app.valuesRange = [min(app.y), max(app.y)];
            
            app.x = [app.x, flip(period - app.x(1:end-1))];
            offset = repmat(0:(round(app.plotRange/period)-1), length(app.x), 1) * period;
            offset = offset(:)';
            % disp(period);
            app.x = repmat(app.x, 1, round(app.plotRange/period));
            app.x = app.x + offset;
            
            app.y = [app.y, flip(app.y(1:end-1))];
            app.y = repmat(app.y, 1, round(app.plotRange/period));
            
            if (app.CalcRootsOnlyCheckBox.Value == 0)
                calculateiterations(app);
                % doesn't have to check for both buttons as they cannot be
                % pressed together but this way it's more explicit what is
                % happening
                if (app.IterationsButton.Value == 1)
                    calculateIterationsColourMaps(app);
                end
                if (app.ConvergenceButton.Value == 1)
                    calculateConvergenceColourMap(app);
                end
            else
                calculateRoots(app);
            end
        end
        
        function calculateRoots(app)
            maxIter = app.IterationSlider.Limits(2);
            [app.roots, period] = findroots(app.A, ...
                'n', app.NPointsSpinner.Value,...
                'maxIter', maxIter, ...
                'tolerance', app.tolerance);
            % Roots where only found within period
            offset = repmat((0:round(app.plotRange/period) - 1), length(app.roots), 1) * period;
            offset = offset(:)';
            app.roots = repmat(app.roots, 1, round(app.plotRange/period));
            app.roots = app.roots + offset;
        end
        
        function calculateiterations(app)
            maxIter = app.IterationSlider.Limits(2);
            
            f = @(x) real(goertzel(app.A, x, true));
            df = @(x) imag(goertzel(app.B, x, true));
            [app.roots, app.iterationsValues, app.iterations] = newtonroots(app.x, f, df, ...
                'maxIter', maxIter, ...
                'tolerance', app.tolerance, ...
                'returnAll', true,...
                'rootRange',[0,app.plotRange]);
            app.roots = uniquetol(app.roots, 10^5*app.tolerance, 'DataScale', 1);
            app.iterationsValues(app.iterationsValues > app.plotRange) = nan;
            app.iterationsValues(app.iterationsValues < 0) = nan; 
            app.roots = app.roots(~isnan(app.roots));
        end
        
        function calculateIterationsColourMaps(app)
            n = length(app.x);
            app.iterationsColourMap = zeros(n, 3);
            
            % Colour non converging points as black
            nanIndices = isnan(app.iterationsValues(end,:));
            nNan = sum(nanIndices);
            app.iterationsColourMap(nanIndices, :) = repmat([0 0 0], nNan, 1);
            
            % Find colour scale
            maxItersPerformed = max(app.iterations(~nanIndices));
            if (isempty(maxItersPerformed))
                maxItersPerformed = app.IterationSlider.Limits(2);
            end
            iterationsNormalised = app.iterations/maxItersPerformed;
            
            % Colour converging points according to scale
            colour = [iterationsNormalised(~nanIndices)', 1-iterationsNormalised(~nanIndices)', zeros(n-nNan,1)];
            app.iterationsColourMap(~nanIndices, :) = colour;
        end
        
        function calculateConvergenceColourMap(app)
            lastX = app.iterationsValues(end,:);
            n = length(lastX);
            
            allPoints = [lastX, app.roots];
            [~, ~, IC] = uniquetol(allPoints, 10^5*app.tolerance, 'DataScale', 1);
            app.convergenceColourMap = ...
                app.palette(mod(IC(1:n), length(app.palette)) + 1,:);
            app.rootsColourMap = app.palette(mod(IC(n+1:end), length(app.palette))+1,:);
            
            % Recolour NaNs to black
            nanIndices = isnan(lastX);
            nNan = sum(nanIndices);
            app.convergenceColourMap(nanIndices,:) = repmat([0 0 0], nNan, 1);
%             
%             lastX = app.iterationsValues(end,:);
%             n = length(lastX);
%             app.convergenceColourMap = zeros(n, 3);
%             app.rootsColourMap = zeros(length(app.roots), 3);
% 
%             for i = 1:length(app.roots)
%                 memebersIdx = ismembertol(lastX, app.roots(i), app.tolerance);
%                 % randomise colour that is not too dark or too light
% %                 minVal = 0.5;
% %                 maxVal = 0.9;
% %                 colour = rand(1, 3) * (maxVal - minVal) + minVal;
%                 colour = app.palette(mod(i, 100)+1,:);
%                 colours = repmat(colour, sum(memebersIdx), 1);
%                 app.convergenceColourMap(memebersIdx, :) = colours;
%                 % disp(lastX);
%                 app.rootsColourMap(i,:) = colour;
%            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, A)
            app.tolerance = 1000*eps(); % Add ability for user to change this from GUI
            app.plotRange = 2*pi;
            app.palette = lines(100);
            if (nargin == 2)
                app.A = A;
                recalculate(app, false);
            else
                recalculate(app);
            end
            redrawaxes(app);
        end

        % Button pushed function: RandomiseButton
        function RandomiseButtonPushed(app, event)
            recalculate(app);
            redrawaxes(app);
        end

        % Value changed function: IterationSlider
        function IterationSliderValueChanged(app, event)
            value = app.IterationSlider.Value;
            value = floor(value);
            app.IterationSlider.Value = value;
            redrawaxes(app);
        end

        % Value changing function: IterationSlider
        function IterationSliderValueChanging(app, event)
            changingValue = event.Value;
            value = floor(changingValue);
            app.IterationSlider.Value = value;
            redrawaxes(app);
        end

        % Value changed function: DrawOriginalPlotCheckBox
        function DrawOriginalPlotCheckBoxValueChanged(app, event)
            % value = app.DrawOriginalPlotCheckBox.Value;
            redrawaxes(app);
        end

        % Value changed function: DrawRootsCheckBox
        function DrawRootsCheckBoxValueChanged(app, event)
            % value = app.DrawRootsCheckBox.Value;
            redrawaxes(app);
        end

        % Button pushed function: IterationDecrementButton
        function IterationDecrementButtonPushed(app, event)
            value = app.IterationSlider.Value;
            if (value > app.IterationSlider.Limits(1))
                app.IterationSlider.Value = value - 1;
                redrawaxes(app);
            end
        end

        % Button pushed function: IterationIncrementButton
        function IterationIncrementButtonPushed(app, event)
            value = app.IterationSlider.Value;
            if (value < app.IterationSlider.Limits(2))
                app.IterationSlider.Value = value + 1;
                redrawaxes(app);
            end
        end

        % Value changed function: NPointsSpinner
        function NPointsSpinnerValueChanged(app, event)
            % value = app.NPointsSpinner.Value;
            recalculate(app, false);
            redrawaxes(app);
        end

        % Button pushed function: RecalcRedrawButton
        function RecalcRedrawButtonPushed(app, event)
            recalculate(app, false);
            redrawaxes(app);
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            value = app.ASlider.Value;
            index = app.IndexSpinner.Value;
            app.A(index+1) = value;
            recalculate(app, false);
            redrawaxes(app);
        end

        % Value changing function: ASlider
        function ASliderValueChanging(app, event)
            if (app.RedrawOnChangeCheckBox.Value == 1)
                changingValue = event.Value;
                index = app.IndexSpinner.Value;
                app.A(index+1) = changingValue;
                recalculate(app, false);
                redrawaxes(app);
            end
        end

        % Value changed function: IndexSpinner
        function IndexSpinnerValueChanged(app, event)
            value = app.IndexSpinner.Value;
            % Degree = 5, index = 6
            % Mam 6 elementów, chce wstawić 7
            if (length(app.A) < value + 1)
                app.A = [app.A zeros(1, value + 1 - length(app.A))];
                app.ASlider.Value = 0;
            else
               app.ASlider.Value = app.A(value + 1); 
            end
        end

        % Selection changed function: ColouringButtonGroup
        function ColouringButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColouringButtonGroup.SelectedObject;
            if (selectedButton == app.IterationsButton)
                calculateIterationsColourMaps(app);
            elseif (selectedButton == app.ConvergenceButton)
                calculateConvergenceColourMap(app);
            end
            recalculate(app, false);
            redrawaxes(app);
        end

        % Button pushed function: PrintAButton
        function PrintAButtonPushed(app, event)
            display(app.A);
        end

        % Value changed function: DrawMarkersCheckBox
        function DrawMarkersCheckBoxValueChanged(app, event)
            % value = app.DrawMarkersCheckBox.Value;
            redrawaxes(app);
        end

        % Value changed function: DrawZeroLineCheckBox
        function DrawZeroLineCheckBoxValueChanged(app, event)
            % value = app.DrawZeroLineCheckBox.Value;
            redrawaxes(app);
        end

        % Button pushed function: PrintRootsButton
        function PrintRootsButtonPushed(app, event)
            display(app.roots);
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            value = app.AEditField.Value;
            if (value == "")
                app.AEditField.Value = sprintf('%.2f ', app.A);
                return;
            end
            strCoeffs = textscan(value,'%f','delimiter',' ');
            lastA = app.A;
            app.A = strCoeffs{:}';
            try
                recalculate(app, false);
                redrawaxes(app);
            catch
                app.A = lastA;
                recalculate(app, false);
                redrawaxes(app);
            end
        end

        % Value changed function: RSizeSpinner
        function RSizeSpinnerValueChanged(app, event)
            % value = app.RSizeSpinner.Value;
            redrawaxes(app);
        end

        % Value changed function: MSizeSpinner
        function MSizeSpinnerValueChanged(app, event)
            % value = app.MSizeSpinner.Value;
            redrawaxes(app);
        end

        % Value changed function: LimitToPeriodCheckBox
        function LimitToPeriodCheckBoxValueChanged(app, event)
            % value = app.LimitToPeriodCheckBox.Value;
        end

        % Button pushed function: ClearInputButton
        function ClearInputButtonPushed(app, event)
            app.A = 0;
            recalculate(app, false);
            redrawaxes(app);
        end

        % Button pushed function: SetZeroButton
        function SetZeroButtonPushed(app, event)
            index = app.IndexSpinner.Value;
            app.A(index + 1) = 0;
            recalculate(app, false);
            redrawaxes(app);
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            value = app.ExamplesDropDown.Value;
            if (value == "None Selected")
                return;
            end
            switch value
                case "Example 1"
                    app.A = [0.25, -0.3, 0.6];
                case "Example 2"
                    app.A = [0 0 1 0 0 1];
                case "Example 3"
                    app.A = [-0.45, -0.30, 0.80, -0.25, -0.75, 0.55];
                case "Example 4"
                    app.A = zeros(1, 1001);
                    app.A(11) = 1;
                    app.A(101) = -1;
                    app.A(1001) = 1;
                case "Example 5"
                    app.A = zeros(1, 8001);
                    app.A(1001) = -1;
                    app.A(3501) = 1;
                    app.A(5001) = 1;
                    app.A(8001) = -1;
                case "Example 6"
                    app.A = zeros(1, 8501);
                    app.A(8301) = 1;
                    app.A(8351) = -1;
                    app.A(8476) = -1;
                    app.A(8501) = 1;
            end
            recalculate(app, false);
            redrawaxes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColouringButtonGroup = uibuttongroup(app.UIFigure);
            app.ColouringButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColouringButtonGroupSelectionChanged, true);
            app.ColouringButtonGroup.Title = 'Colouring';
            app.IterationsButton = uiradiobutton(app.ColouringButtonGroup);
            app.IterationsButton.Text = 'Iterations';
            app.IterationsButton.Value = true;
            app.ConvergenceButton = uiradiobutton(app.ColouringButtonGroup);
            app.ConvergenceButton.Text = 'Convergence';
            app.NoColouringButton = uiradiobutton(app.ColouringButtonGroup);
            app.NoColouringButton.Text = 'No Colouring';
            app.DrawOriginalPlotCheckBox = uicheckbox(app.UIFigure);
            app.DrawOriginalPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawOriginalPlotCheckBoxValueChanged, true);
            app.DrawOriginalPlotCheckBox.Text = 'Draw Original Plot';
            app.DrawOriginalPlotCheckBox.Value = true;
            app.IterationSliderLabel = uilabel(app.UIFigure);
            app.IterationSliderLabel.Text = 'Iteration';
            app.IterationSlider = uislider(app.UIFigure);
            app.IterationSlider.Limits = [0 30];
            app.IterationSlider.ValueChangedFcn = createCallbackFcn(app, @IterationSliderValueChanged, true);
            app.IterationSlider.ValueChangingFcn = createCallbackFcn(app, @IterationSliderValueChanging, true);
            app.IterationSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.DegreeSpinnerLabel = uilabel(app.UIFigure);
            app.DegreeSpinnerLabel.Tooltip = {'Degree of the plotted random polynomial'};
            app.DegreeSpinnerLabel.Text = 'Degree';
            app.DegreeSpinner = uispinner(app.UIFigure);
            app.DegreeSpinner.Limits = [0 Inf];
            app.DegreeSpinner.Tooltip = {'Degree of the trigonometric polynomial. I.e the index of last coefficient.'};
            app.DegreeSpinner.Value = 5;
            app.RandomiseButton = uibutton(app.UIFigure, 'push');
            app.RandomiseButton.ButtonPushedFcn = createCallbackFcn(app, @RandomiseButtonPushed, true);
            app.RandomiseButton.Text = 'Randomise';
            app.DrawRootsCheckBox = uicheckbox(app.UIFigure);
            app.DrawRootsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawRootsCheckBoxValueChanged, true);
            app.DrawRootsCheckBox.Text = 'Draw Roots';
            app.DrawRootsCheckBox.Value = true;
            app.IterationIncrementButton = uibutton(app.UIFigure, 'push');
            app.IterationIncrementButton.ButtonPushedFcn = createCallbackFcn(app, @IterationIncrementButtonPushed, true);
            app.IterationIncrementButton.Text = '+';
            app.IterationDecrementButton = uibutton(app.UIFigure, 'push');
            app.IterationDecrementButton.ButtonPushedFcn = createCallbackFcn(app, @IterationDecrementButtonPushed, true);
            app.IterationDecrementButton.Text = '-';
            app.NPointsLabel = uilabel(app.UIFigure);
            app.NPointsLabel.Tooltip = {'Degree of the plotted random polynomial'};
            app.NPointsLabel.Text = 'NPoints';
            app.NPointsSpinner = uispinner(app.UIFigure);
            app.NPointsSpinner.Step = 50;
            app.NPointsSpinner.Limits = [2 Inf];
            app.NPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @NPointsSpinnerValueChanged, true);
            app.NPointsSpinner.Tooltip = {'Number of points at which the polynomial is evaluated'};
            app.NPointsSpinner.Value = 501;
            app.RecalcRedrawButton = uibutton(app.UIFigure, 'push');
            app.RecalcRedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RecalcRedrawButtonPushed, true);
            app.RecalcRedrawButton.Text = 'Recalc & Redraw';
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [-1 1];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanging, true);
            app.IndexSpinnerLabel = uilabel(app.UIFigure);
            app.IndexSpinnerLabel.Text = 'Index';
            app.IndexSpinner = uispinner(app.UIFigure);
            app.IndexSpinner.Limits = [0 Inf];
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexSpinnerValueChanged, true);
            app.RedrawOnChangeCheckBox = uicheckbox(app.UIFigure);
            app.RedrawOnChangeCheckBox.Text = 'Redraw On Change';
            app.RedrawOnChangeCheckBox.Value = true;
            app.PrintAButton = uibutton(app.UIFigure, 'push');
            app.PrintAButton.ButtonPushedFcn = createCallbackFcn(app, @PrintAButtonPushed, true);
            app.PrintAButton.Text = 'Print A';
            app.DrawMarkersCheckBox = uicheckbox(app.UIFigure);
            app.DrawMarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawMarkersCheckBoxValueChanged, true);
            app.DrawMarkersCheckBox.Text = 'Draw Markers';
            app.DrawMarkersCheckBox.Value = true;
            app.DrawZeroLineCheckBox = uicheckbox(app.UIFigure);
            app.DrawZeroLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawZeroLineCheckBoxValueChanged, true);
            app.DrawZeroLineCheckBox.Text = 'Draw Zero Line';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'text');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.ManualInputLabel = uilabel(app.UIFigure);
            app.ManualInputLabel.Text = 'Manual Input';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.StyleLabel = uilabel(app.UIFigure);
            app.StyleLabel.Text = 'Style';
            app.ExamplesDropDownLabel = uilabel(app.UIFigure);
            app.ExamplesDropDownLabel.Text = 'Examples';
            app.ExamplesDropDown = uidropdown(app.UIFigure);
            app.ExamplesDropDown.Items = {'Example 1', 'Example 2', 'Example 3', 'Example 4', 'Example 5', 'Example 6', 'None Selected'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.ExamplesDropDown.Value = 'None Selected';
            app.PrintRootsButton = uibutton(app.UIFigure, 'push');
            app.PrintRootsButton.ButtonPushedFcn = createCallbackFcn(app, @PrintRootsButtonPushed, true);
            app.PrintRootsButton.Text = 'Print Roots';
            app.DebugMenuLabel = uilabel(app.UIFigure);
            app.DebugMenuLabel.Text = 'Debug Menu';
            app.MSizeSpinnerLabel = uilabel(app.UIFigure);
            app.MSizeSpinnerLabel.Text = 'MSize';
            app.MSizeSpinner = uispinner(app.UIFigure);
            app.MSizeSpinner.Limits = [1 100];
            app.MSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MSizeSpinnerValueChanged, true);
            app.MSizeSpinner.Value = 5;
            app.RSizeSpinnerLabel = uilabel(app.UIFigure);
            app.RSizeSpinnerLabel.Text = 'RSize';
            app.RSizeSpinner = uispinner(app.UIFigure);
            app.RSizeSpinner.Limits = [1 100];
            app.RSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @RSizeSpinnerValueChanged, true);
            app.RSizeSpinner.Value = 10;
            app.CalcRootsOnlyCheckBox = uicheckbox(app.UIFigure);
            app.CalcRootsOnlyCheckBox.Text = 'Calc Roots Only';
            app.LimitToPeriodCheckBox = uicheckbox(app.UIFigure);
            app.LimitToPeriodCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitToPeriodCheckBoxValueChanged, true);
            app.LimitToPeriodCheckBox.Text = 'Limit To Period';
            app.ClearInputButton = uibutton(app.UIFigure, 'push');
            app.ClearInputButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInputButtonPushed, true);
            app.ClearInputButton.Text = 'Clear Input';
            app.SetZeroButton = uibutton(app.UIFigure, 'push');
            app.SetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroButtonPushed, true);
            app.SetZeroButton.Text = 'Set Zero';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualisation of trigonometric polynomial root finding')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'P(x)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualisation_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end